classdef LDMP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LDMPUIFigure                 matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SampleTab                    matlab.ui.container.Tab
        GridLayout_Sample            matlab.ui.container.GridLayout
        Panel_Options                matlab.ui.container.Panel
        GridLayout_Options           matlab.ui.container.GridLayout
        ExportButton_Commands        matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        DisplayLengthDropDownLabel   matlab.ui.control.Label
        DisplayLengthDropDown        matlab.ui.control.DropDown
        Lamp                         matlab.ui.control.Lamp
        PanelDisplayChannels         matlab.ui.container.Panel
        GridLayout_DisplayChannels   matlab.ui.container.GridLayout
        HallSensorsAnalogInputDisplayChannelsLabel  matlab.ui.control.Label
        HallSensor_1                 matlab.ui.control.CheckBox
        HallSensor_2                 matlab.ui.control.CheckBox
        HallSensor_3                 matlab.ui.control.CheckBox
        HallSensor_4                 matlab.ui.control.CheckBox
        HallSensor_5                 matlab.ui.control.CheckBox
        HallSensor_6                 matlab.ui.control.CheckBox
        HallSensor_7                 matlab.ui.control.CheckBox
        HallSensor_8                 matlab.ui.control.CheckBox
        SampleRatekHzSpinnerLabel    matlab.ui.control.Label
        SampleRatekHzSpinner         matlab.ui.control.Spinner
        DAQCardDropDownLabel         matlab.ui.control.Label
        DAQCardDropDown              matlab.ui.control.DropDown
        PauseButton                  matlab.ui.control.StateButton
        Panel_Display                matlab.ui.container.Panel
        GridLayout_Display           matlab.ui.container.GridLayout
        OperationLogTextAreaLabel    matlab.ui.control.Label
        OperationLogTextArea         matlab.ui.control.TextArea
        RefDispEditFieldLabel        matlab.ui.control.Label
        RefDispEditField             matlab.ui.control.NumericEditField
        MeaDispEditFieldLabel        matlab.ui.control.Label
        MeaDispEditField             matlab.ui.control.NumericEditField
        mmLabel_1                    matlab.ui.control.Label
        mmLabel_2                    matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        ResetZeroButton              matlab.ui.control.Button
        UIAxes_Encoder               matlab.ui.control.UIAxes
        UIAxes_HallSensor            matlab.ui.control.UIAxes
        UIAxes_HallSensor_Encoder    matlab.ui.control.UIAxes
        AnalyzeTab                   matlab.ui.container.Tab
        GridLayout_Analyze           matlab.ui.container.GridLayout
        TextArea_Spectrum            matlab.ui.control.TextArea
        Panel_Spectrum               matlab.ui.container.Panel
        GridLayout_Spectrum          matlab.ui.container.GridLayout
        CalculateButton              matlab.ui.control.Button
        ExportButton_Spectrum        matlab.ui.control.Button
        OperationLogTextArea_2Label  matlab.ui.control.Label
        OperationLogTextArea_Ana     matlab.ui.control.TextArea
        LoadButton_Spectrum          matlab.ui.control.Button
        UIAxes_Spectrum              matlab.ui.control.UIAxes
        LinearDisplacementMeasurementPlatformLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Parameters
        EncoderRange = 2^31; % Range of Encoder
        EncoderRatio = 200; % Ratio between displacement and Encoder
        DisplayChannels; % Channels to be displayed in UIAxes
        % PlotLegend = ... % Legend for data from Hall sensors
        %    ['#1', '#2', '#3', '#4', '#5', '#6', '#7', '#8'];
        % PlotLength; % Plot length of data in UIAxes
        RecordData = struct(... % Data recorded
            'Time', [], ...
            'HallSensorData', [], ...
            'EncoderData', [], ...
            'Length', 0);
        EncoderZeroValue = 0; % The zero position of Encoder
        DataSpectrum = struct(... % The spectrum of RecordData
            'RoP', [], ... % Repetition of postion
            'FFTVal', [], ...
            'Magnitude', [], ...
            'Phase', [], ...
            'Length', 0);
        CalibratePara = struct(... % Parameters for calibration
            'NormSig', [], ...
            'NormPara', [], ...
            'Step', 0);
        
        % System components
        SampleSession; % Data acquisition (DAQ) session
        IsCreated_SS = false; % whether SampleSession is created
        SessionListener; % Listener for DAQ
        VirtualSession; % Timer for virtual sampling
        IsCreated_VS = false; % whether SampleSession is created
        SessionTimer; % Timer for sampling and processing
        IsCreated_ST = false; % whether SessionTimer is created
        StartTime; % Time app has started
        DefExportPath = []; % Default path for export
        OperationLog = {}; % Operation log
        OperationLogLength = 0; % Length of OperationLog
        
        % PlotData
        PlotLine_HSE;
        PlotLine_HS;
        PlotLine_E;
        PlotLine_FFT;
        PlotLine_Peaks;
    end
    
    methods (Access = private)
        
        % Actions taken after starting sampling
        function Stat = StartSampling(app)
            if ~app.IsCreated_SS
                % Create session and listener
                app.SampleSession = daq.createSession('ni'); % create DAQ
                
                % Hall sensors
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai0', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai1', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai2', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai3', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai4', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai5', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai6', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai7', 'Voltage');
                
                % Encoder
                Encoder1 = addCounterInputChannel(app.SampleSession, 'Dev1', 'ctr0', 'Position');
                
                app.IsCreated_SS = true;
            end
            
            % Hall sensors
            app.SampleSession.Rate = app.SampleRatekHzSpinner.Value * 1e3; % Sample rate
            app.SampleSession.IsContinuous = true; % Data will be sampled continuously or not
            app.SampleSession.NotifyWhenDataAvailableExceeds = 1e3; % Define data length for triggering 'DataAvailable' event
            app.SessionListener = addlistener(app.SampleSession, 'DataAvailable', @app.ProcessData);
            
            % Encoder
            Encoder1.EncoderType = 'X4'; % Counter Mode: X1/X2/X4
            Encoder1.ZResetEnable = true; % Set the zero
            Encoder1.ZResetCondition = 'BothLow'; % Options: 'BothHigh'/'BothLow'/'AHigh'/'BHigh'
            Encoder1.ZResetValue = 0; % The 'zero'
            app.EncoderRatio = 200; % 200 when EncoderType is X4
            
            startBackground(app.SampleSession); % Start DAQ session
            
            Stat = 1;
        end
        
        % Actions taken after ending sampling
        function Stat = EndSampling(app)
            if app.DAQCardDropDown.Value == 1
                if app.IsCreated_SS
                    stop(app.SampleSession);
                end
            else
                if app.IsCreated_VS
                    stop(app.VirtualSession);
                end
            end
            if app.IsCreated_ST
                stop(app.SessionTimer);
            end
            
            Stat = 1;
        end
        
        % Save new data stored in SessionListener and refresh app
        function ProcessData(app, ~, event)
            NewDataLength = length(event.TimeStamps);
            StartRow = app.RecordData.Length + 1;
            EndRow = app.RecordData.Length + NewDataLength;
            app.RecordData.Length = EndRow;
            app.RecordData.Time(StartRow:EndRow) = event.TimeStamps;
            app.RecordData.HallSensorData(StartRow:EndRow, :) = event.Data(:, 1:end-1);
            % EncoderData needs preprocess
            EncoderData_Raw = event.Data(:, end);
            IsNegative = EncoderData_Raw > app.EncoderRange;
            EncoderData_Raw(IsNegative) = EncoderData_Raw(IsNegative) - 2*app.EncoderRange; % unsigned int -> signed int
            EncoderData_Raw = EncoderData_Raw / app.EncoderRatio;
            EncoderData_Raw = EncoderData_Raw - app.EncoderZeroValue;
            app.RecordData.EncoderData(StartRow:EndRow, :) = EncoderData_Raw;
        end
        
        % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
        % Func. VirtualSession
        % Run this func. when there is no DAQ card provided
        function Stat = VirtualSampling(app)
            % Use timer to run in background
            if ~app.IsCreated_VS
                app.VirtualSession = timer;
                app.IsCreated_VS = true;
            end
            app.VirtualSession.BusyMode = 'queue';
            app.VirtualSession.ExecutionMode = 'fixedRate';
            app.VirtualSession.Name = 'VirtualTimer';
            app.VirtualSession.Period = 0.5;
            app.VirtualSession.StartDelay = 0;
            app.VirtualSession.StartFcn = @app.VirtualStart;
            app.VirtualSession.StopFcn = @app.VirtualStop;
            app.VirtualSession.TimerFcn = @app.VirtualProcess;
            app.VirtualSession.TasksToExecute = Inf;
            
            start(app.VirtualSession);
            
            Stat = 1;
        end
        
        function VirtualStart(app, obj, ~)
            % Operations conducted after VirtualSession starts
        end
        
        function VirtualStop(app, obj, ~)
            % Do not delete the obj since the user may resume from pause
            % delete(obj);
        end
        
        function VirtualProcess(app, ~, ~)
            TimeInterval = 1e-3 * app.SampleRatekHzSpinner.Value;
            DataLength = floor(app.VirtualSession.Period / TimeInterval);
            EndTime = toc(app.StartTime);
            event.TimeStamps = EndTime + TimeInterval * ((1-DataLength):0);
            event.Data = zeros(DataLength, 9);
            event.Data(:, 1:8) = sin(repmat((event.TimeStamps'*5.7*pi/app.VirtualSession.Period), 1, 8) + repmat(1:8, DataLength, 1));
            event.Data(:, end) = event.TimeStamps * 30 + 2;
            
            NewDataLength = length(event.TimeStamps);
            StartRow = app.RecordData.Length + 1;
            EndRow = app.RecordData.Length + NewDataLength;
            app.RecordData.Length = EndRow;
            app.RecordData.Time(StartRow:EndRow) = event.TimeStamps;
            app.RecordData.HallSensorData(StartRow:EndRow, :) = event.Data(:, 1:end-1);
            % EncoderData needs preprocess
            EncoderData_Raw = event.Data(:, end);
            IsNegative = EncoderData_Raw > app.EncoderRange;
            EncoderData_Raw(IsNegative) = EncoderData_Raw(IsNegative) - 2*app.EncoderRange; % unsigned int -> signed int
            EncoderData_Raw = EncoderData_Raw / app.EncoderRatio;
            EncoderData_Raw = EncoderData_Raw - app.EncoderZeroValue;
            app.RecordData.EncoderData(StartRow:EndRow, :) = EncoderData_Raw;
        end
        % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
        
        % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
        % Func. SessionTimer
        function Stat = StartTimer(app)
            % Use timer to run in background
            if ~app.IsCreated_ST
                app.SessionTimer = timer;
                app.IsCreated_ST = true;
            end
            app.SessionTimer.BusyMode = 'queue';
            app.SessionTimer.ExecutionMode = 'fixedRate';
            app.SessionTimer.Name = 'ProcessTimer';
            app.SessionTimer.Period = 0.5;
            app.SessionTimer.StartDelay = 0;
            app.SessionTimer.StartFcn = @app.TimerResume;
            app.SessionTimer.StopFcn = @app.TimerPause;
            app.SessionTimer.TimerFcn = @app.TimerProcess;
            app.SessionTimer.TasksToExecute = Inf;
            
            start(app.SessionTimer);
            
            Stat = 1;
        end
        
        function TimerResume(app, obj, ~)
            % Operation conducted after TimerSession Starts
        end
        
        function TimerPause(app, obj, ~)
            % Do not delete the obj since the user may resume from pause
            % delete(obj);
        end
        
        function TimerProcess(app, ~, ~)
            UpdateEditField(app);
            UpdatePlot(app);
        end
        % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
        
        % Refresh Log
        function Stat = RefreshLog(app, tab, NewText)
            switch tab
                case 1 % [Sample] tab
                    app.OperationLogLength = app.OperationLogLength+1;
                    app.OperationLog{app.OperationLogLength} = NewText;
                    if app.OperationLogLength < 5
                        app.OperationLogTextArea.Value = app.OperationLog;
                    else
                        app.OperationLogTextArea.Value = app.OperationLog(end-4:end);
                    end
                case 2 % [Analyze] tab
                    app.OperationLogTextArea_Ana.Value = NewText;
            end
            
            Stat = 1;
        end
        
        % Update var. in EditFields
        function Stat = UpdateEditField(app)
            app.RefDispEditField.Value = app.RecordData.EncoderData(end);
            app.MeaDispEditField.Value = CalculateDisp(app, app.RecordData.HallSensorData(end, :)');
            
            Stat = 1;
        end
        
        % Update UIAxes
        function Stat = UpdatePlot(app)
            
            if ~isempty(app.RecordData.Time)
                DisplayLength = app.DisplayLengthDropDown.Value - 1;
                
                xlim(app.UIAxes_HallSensor, app.RecordData.Time(end)*1e3 - ...
                    [DisplayLength / app.SampleRatekHzSpinner.Value, 0]);
                ylim(app.UIAxes_HallSensor, [-5, 5]);
                % xlim(app.UIAxes_HallSensor_Encoder, []);
                % ylim(app.UIAxes_HallSensor_Encoder, []);
                xlim(app.UIAxes_Encoder, app.RecordData.Time(end)*1e3 - ...
                    [DisplayLength / app.SampleRatekHzSpinner.Value, 0]);
                % ylim(app.UIAxes_Encoder, []);
                
                for iC = 1:8
                    if eval(sprintf('app.HallSensor_%d.Value', iC))
                        app.PlotLine_HS(iC).XData = app.RecordData.Time(max(1, end-DisplayLength):end) * 1e3;
                        app.PlotLine_HS(iC).YData = app.RecordData.HallSensorData(max(1, end-DisplayLength):end, iC);
                        app.PlotLine_HSE(iC).XData = app.RecordData.EncoderData(max(1, end-DisplayLength):end);
                        app.PlotLine_HSE(iC).YData = app.RecordData.HallSensorData(max(1, end-DisplayLength):end, iC);
                    else
                        app.PlotLine_HS(iC).XData = [];
                        app.PlotLine_HS(iC).YData = [];
                        app.PlotLine_HSE(iC).XData = [];
                        app.PlotLine_HSE(iC).YData = [];
                    end
                end
                app.PlotLine_E.XData = app.RecordData.Time(max(1, end-DisplayLength):end) * 1e3;
                app.PlotLine_E.YData = app.RecordData.EncoderData(max(1, end-DisplayLength):end);
                
                Stat = 1;
            else
                Stat = 0;
            end
        end
        
        % Calibrate the model para.
        function Stat = CalibrateModelPara(app, CalibrateData)
            if CalibrateData.Length < 10 % Not enough data for calibration: terminate calibration
                Stat = 0;
            else % Start calibration
                % Average HallSensorData with same EncoderData respectively
                [uEncoderData, ~, idCalibrateData] = unique(CalibrateData.EncoderData, 'sorted');
                uLength = length(uEncoderData);
                uHallSensorData = zeros(uLength, size(CalibrateData.HallSensorData, 2));
                for iC = 1:size(uHallSensorData, 2)
                    uHallSensorData(:, iC) = accumarray(idCalibrateData, ...
                        CalibrateData.HallSensorData(:, iC), [uLength, 1], @mean);
                end
                minEncoderData = uEncoderData(1);
                maxEncoderData = uEncoderData(end);
                interpStep = 1e-3;
                interpEncoderData = minEncoderData:interpStep:(maxEncoderData+interpStep);
                interpHallSensorData = interp1(uEncoderData, uHallSensorData, interpEncoderData, 'makima');
                
                % Normalize
                [NormSignal, app.CalibratePara.NormPara] = mapminmax(interpHallSensorData');
                app.CalibratePara.NormSig = NormSignal';
                app.CalibratePara.Step = interpStep;
                
                %==============================================================================
                % Contributor:    %粗算周期与1，5信号相位差,用于计算迭代初值
                % Wen Tingrui     %period_n：信号序号的周期；period_disp：位移周期
                %                 %phase_1_5：1，5信号相位差，范围0~2*pi，以信号5超前信号1的方向为正
                %                 %------------------------------------------------------------------------------
                %                 %全局变量：位移周期period_disp(mm)，1、5信号相位差phase_1_5
                %                 global period_disp;global phase_1_5;
                % Changes:
                % period_disp --> app.CalibratePara.period_disp
                % phase_1_5 --> app.CalibratePara.phase_1_5
                
                [~,n_min_1]=min(app.CalibratePara.NormSig(:,1));[~,n_max_1]=max(app.CalibratePara.NormSig(:,1));
                [~,n_min_5]=min(app.CalibratePara.NormSig(:,5));[~,n_max_5]=max(app.CalibratePara.NormSig(:,5));
                period_n=2*abs(n_min_1-n_max_1);
                app.CalibratePara.period_disp=period_n*interpStep;
                if n_min_5>n_min_1
                    app.CalibratePara.phase_1_5=(n_min_5-n_min_1)/period_n*2*pi;
                else
                    app.CalibratePara.phase_1_5=(n_max_5-n_max_1)/period_n*2*pi;
                end
                %==============================================================================
                
                Stat = 1;
            end
        end
        
        % Calculate Mea. Disp.
        function Disp = CalculateDisp(app, Y_measure)
            % Input: Y_measure: 8 * 1 Vector
            
            % Changes in var.
            % Contributor:%全局变量：位移步长step(mm)，标准信号数据norm_signal，信号归一化参数norm_para
            %             global step;global norm_signal;global norm_para;
            % Wen Tingrui %全局变量：位移周期period_disp(mm)，1、5信号相位差phase_1_5
            %             global period_disp;global phase_1_5;
            % Changes:
            % step --> app.CalibratePara.Step
            % norm_signal --> app.CalibratePara.NormSig
            % norm_para --> app.CalibratePara.NormPara
            % period_disp --> app.CalibratePara.period_disp
            % phase_1_5 --> app.CalibratePara.phase_1_5
            
            %信号归一化
            Y_measure=mapminmax('apply',Y_measure,app.CalibratePara.NormPara);
            
            %计算初值
            %0位移处1，5信号
            y1=app.CalibratePara.NormSig(1,1);y5=app.CalibratePara.NormSig(1,5);
            %0位移处1信号的相位
            phi_0=atan2((y5-y1*cos(app.CalibratePara.phase_1_5))/sin(app.CalibratePara.phase_1_5),y1);
            %待测位移处1，5信号
            y1=Y_measure(1);y5=Y_measure(5);
            %待测位移处1信号的相位
            phi_measure=atan2((y5-y1*cos(app.CalibratePara.phase_1_5))/sin(app.CalibratePara.phase_1_5),y1);
            %待测位移的估计值：X
            if phi_measure<phi_0
                phi_measure=phi_measure+2*pi;
            end
            X=app.CalibratePara.period_disp/2/pi*(phi_measure-phi_0);
            
            %高斯牛顿迭代
            % step=0.01;%位移步长
            for i=1:10
                num_X=floor(X/app.CalibratePara.Step);
                J=(app.CalibratePara.NormSig(num_X+2,:)-app.CalibratePara.NormSig(num_X+1,:))/app.CalibratePara.Step;
                G=app.CalibratePara.NormSig(num_X+1,:)+(app.CalibratePara.NormSig(num_X+2,:)-app.CalibratePara.NormSig(num_X+1,:))*(X/app.CalibratePara.Step-num_X);
                G=G'-Y_measure;J=J';
                X=X+(J'*J)\(J'*(-G));
                %如果迭代过程中位移<0，应补上一个周期长度；
                if X<0
                    X=X+app.CalibratePara.period_disp;
                    i=1;%由于周期粗略，应重置迭代步数
                elseif X>app.CalibratePara.period_disp
                    X=X-app.CalibratePara.period_disp;
                    i=1;
                end
            end
            Disp=X;
        end
        
        % Calculate FFT of app.RecordData
        function Stat = CalFFT(app, SampleData)
            if SampleData.Length <= 0 % No data provided in SampleData
                % Spectrum
                app.DataSpectrum.RoP = [];
                app.DataSpectrum.FFTVal = [];
                app.DataSpectrum.Magnitude = [];
                app.DataSpectrum.Phase = [];
                app.DataSpectrum.Length = 0;
                
                Stat = 0;
            else
                % Average HallSensorData with same EncoderData respectively
                [uEncoderData, ~, idSampleData] = unique(SampleData.EncoderData, 'sorted');
                uLength = length(uEncoderData);
                uHallSensorData = zeros(uLength, size(SampleData.HallSensorData, 2));
                for iC = 1:size(uHallSensorData, 2)
                    uHallSensorData(:, iC) = accumarray(idSampleData, ...
                        SampleData.HallSensorData(:, iC), [uLength, 1], @mean);
                end
                minEncoderData = uEncoderData(1);
                maxEncoderData = uEncoderData(end);
                interpStep = 1e-3;
                interpEncoderData = minEncoderData:interpStep:(maxEncoderData+interpStep);
                interpHallSensorData = interp1(uEncoderData, uHallSensorData, interpEncoderData, 'makima');
                
                % For better display effect, FFT is conducted on integral
                % period of data
                Pitch = 32; % Distance between neighboring magnetic block (mm)
                PitchNum = Pitch / interpStep; % Number of interpEncoderData in one pitch
                PeriodNum = floor((length(interpEncoderData) / PitchNum)); % Number of pitches interpEncoderData covers
                if PeriodNum < 1
                    fftLength = length(interpEncoderData);
                    % fftEncoderData = interpEncoderData; % Unused
                    fftHallSensorData = interpHallSensorData;
                else
                    fftLength = PitchNum * PeriodNum;
                    % fftEncoderData = interpEncoderData(1:fftLength); % Unused
                    fftHallSensorData = mean(interpHallSensorData(1:fftLength, :), 2);
                end
                
                % Spectrum
                app.DataSpectrum.RoP = (0:(fftLength-1))/fftLength/interpStep;
                app.DataSpectrum.FFTVal = fft(fftHallSensorData, fftLength)/fftLength;
                app.DataSpectrum.Magnitude = abs(app.DataSpectrum.FFTVal);
                app.DataSpectrum.Phase = angle(app.DataSpectrum.FFTVal);
                app.DataSpectrum.Length = fftLength;
                
                Stat = 1;
            end
        end
        
        % Display the result of fun. CalFFT
        function Stat = DisplaySpectrum(app)
            if app.DataSpectrum.Length == 0 % RecordData is empty
                % RefreshLog(app, 2, 'ERROR: data unexists.');
                
                Stat = 0;
            else
                if mod(app.DataSpectrum.Length, 2) == 0 % even
                    PlotLength = app.DataSpectrum.Length/2;
                    MagSingleSided = 2 * app.DataSpectrum.Magnitude(1:PlotLength);
                else % odd
                    PlotLength = (app.DataSpectrum.Length+1)/2;
                    MagSingleSided(1:end-1) = 2 * app.DataSpectrum.Magnitude(1:PlotLength-1);
                    MagSingleSided(end) = app.DataSpectrum.Magnitude(PlotLength);
                end
                app.PlotLine_FFT.XData = app.DataSpectrum.RoP(1:PlotLength);
                app.PlotLine_FFT.YData = MagSingleSided;
                
                [pks, locs] = findpeaks(app.PlotLine_FFT.YData, 'NPeaks', 10);
                PeakInfo = sprintf('Primary peaks: \r\n\r\n');
                for iRow = 1:min(7, length(locs))
                    PeakInfo = [PeakInfo, ...
                        sprintf('#%d\r\n\tFrequency: %f\r\n\tMagnitude: %f\r\n\r\n', ...
                        iRow, app.PlotLine_FFT.XData(locs(iRow)), pks(iRow))];
                end
                app.PlotLine_Peaks.XData = app.PlotLine_FFT.XData(locs);
                app.PlotLine_Peaks.YData = pks;
                xlim(app.UIAxes_Spectrum, [0, app.PlotLine_FFT.XData(locs(end))]);
                % ylim(app.UIAxes_Spectrum, [1e-5 3]);
                
                app.TextArea_Spectrum.Value = PeakInfo;
                
                Stat = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartTime = tic;
            
            app.PlotLine_HS = plot(app.UIAxes_HallSensor, 0, zeros(8, 1));
            app.PlotLine_E = plot(app.UIAxes_Encoder, 0, 0);
            app.PlotLine_HSE = plot(app.UIAxes_HallSensor_Encoder, 0, zeros(8, 1));
            % Set the color of the plotline the same as the option's fontcolor
            for iC = 1:8
                app.PlotLine_HS(iC).Color = eval(sprintf('app.HallSensor_%d.FontColor', iC));
                app.PlotLine_HS(iC).LineWidth = 1;
                app.PlotLine_HSE(iC).Color = eval(sprintf('app.HallSensor_%d.FontColor', iC));
                app.PlotLine_HSE(iC).LineWidth = 1;
            end
            app.PlotLine_E.Color = [0.00, 0.45, 0.74];
            app.PlotLine_E.LineWidth = 1;
            
            hold(app.UIAxes_Spectrum, 'on');
            app.PlotLine_FFT = plot(app.UIAxes_Spectrum, 0, 0);
            app.PlotLine_FFT.Color = [0.00, 0.45, 0.74];
            app.PlotLine_FFT.LineWidth = 1;
            app.PlotLine_Peaks = plot(app.UIAxes_Spectrum, 0, 0);
            app.PlotLine_Peaks.Color = [0.00, 0.45, 0.74];
            app.PlotLine_Peaks.LineStyle = 'none';
            app.PlotLine_Peaks.LineWidth = 1;
            app.PlotLine_Peaks.Marker = 'v';
            app.PlotLine_Peaks.MarkerFaceColor = [0.00, 0.45, 0.74];
            set(app.UIAxes_Spectrum, 'YMinorTick', 'on', 'YScale', 'log');
            legend(app.UIAxes_Spectrum, {'Spectrum', 'Peaks'});
            
            CalibrateData = load('CalibrateData.mat', '-mat');
            CalibrateModelPara(app, CalibrateData.ExportData);
        end

        % Close request function: LDMPUIFigure
        function LDMPUIFigureCloseRequest(app, event)
            % Release the DAQ session
            if app.IsCreated_SS
                stop(app.SampleSession);
                delete(app.SessionListener);
                delete(app.SampleSession);
                app.IsCreated_SS = false;
            end
            % Stop and release the timer
            if app.IsCreated_VS
                stop(timerfind('Name', 'VirtualTimer'));
                delete(timerfind('Name', 'VirtualTimer'));
                app.IsCreated_VS = false;
            end
            if app.IsCreated_ST
                stop(timerfind('Name', 'ProcessTimer'));
                delete(timerfind('Name', 'ProcessTimer'));
                app.IsCreated_ST = false;
            end
            delete(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value
                % LDMP on
                % Reset data, since the parameters may change already
                app.RecordData.Time = [];
                app.RecordData.HallSensorData = [];
                app.RecordData.EncoderData = [];
                app.RecordData.Length = 0;
                
                % Start sampling
                if app.DAQCardDropDown.Value == 1
                    StartSampling(app);
                else
                    VirtualSampling(app);
                end
                
                % Start timer
                StartTimer(app);
                
                % Config components properties
                app.DAQCardDropDown.Enable = 'off';
                app.SampleRatekHzSpinner.Enable = 'off';
                app.ExportButton_Commands.Enable = 'off';
                app.CalibrateButton.Enable = 'off';
                app.ResetZeroButton.Enable = 'off';
                app.CalculateButton.Enable = 'off';
                app.LoadButton_Spectrum.Enable = 'off';
                app.ExportButton_Spectrum.Enable = 'off';
                app.PauseButton.Enable = 'on';
                app.Lamp.Color = [0 1 0];
                RefreshLog(app, 1, 'LDMP sampling started...');
            else
                % LDMP off
                EndSampling(app);
                
                % Config components properties
                app.DAQCardDropDown.Enable = 'on';
                app.SampleRatekHzSpinner.Enable = 'on';
                app.ExportButton_Commands.Enable = 'on';
                app.CalibrateButton.Enable = 'on';
                app.ResetZeroButton.Enable = 'on';
                app.CalculateButton.Enable = 'on';
                app.LoadButton_Spectrum.Enable = 'on';
                app.ExportButton_Spectrum.Enable = 'on';
                app.PauseButton.Value = false;
                app.PauseButton.Text = 'Pause';
                app.PauseButton.BackgroundColor = [0.47,0.67,0.19];
                app.PauseButton.Enable = 'off';
                app.Lamp.Color = [0.65 0.65 0.65];
                RefreshLog(app, 1, 'LDMP sampling ended.');
            end
        end

        % Value changed function: PauseButton
        function PauseButtonPushed(app, event)
            if ~app.PauseButton.Value
                % Resume
                if app.DAQCardDropDown.Value == 1
                    startBackground(app.SampleSession);
                else
                    start(app.VirtualSession);
                end
                start(app.SessionTimer);
                
                app.PauseButton.Text = 'Pause';
                app.PauseButton.BackgroundColor = [0.47,0.67,0.19];
                app.Lamp.Color = [0 1 0];
                app.CalculateButton.Enable = 'off';
                app.LoadButton_Spectrum.Enable = 'off';
                app.ExportButton_Spectrum.Enable = 'off';
                RefreshLog(app, 1, 'Sampling resumed...');
            else
                % Pause
                EndSampling(app);
                
                app.PauseButton.Text = 'Resume';
                app.PauseButton.BackgroundColor = [1.00,0.41,0.16];
                app.Lamp.Color = [1 1 0];
                app.CalculateButton.Enable = 'on';
                app.LoadButton_Spectrum.Enable = 'on';
                app.ExportButton_Spectrum.Enable = 'on';
                RefreshLog(app, 1, 'Sampling paused...');
            end
        end

        % Button pushed function: ExportButton_Commands
        function ExportButton_CommandsPushed(app, event)
            [ExportFile, ExportPath, ExportType] = uiputfile( ...
                {'*.mat','MAT-files (*.mat)'; ...
                '*.txt','Text Files'; ...
                '*.*', 'All Files'}, ...
                'Export Sensor Data', [app.DefExportPath, 'ExportData.mat']);
            figure(app.LDMPUIFigure);
            % Operation cancelled
            if ExportPath == 0
                RefreshLog(app, 1, 'Export operation cancelled.');
            else
                % Save file
                app.DefExportPath = ExportPath;
                ExportData = app.RecordData;
                switch ExportType
                    case 1
                        save([ExportPath, ExportFile], 'ExportData', '-mat');
                    case 2
                        fileID = fopen([ExportPath, ExportFile], 'a');
                        fprintf(fileID, 'Data recorded by LDMP\r\n');
                        fprintf(fileID, 'Date & Time: %s\r\n\r\n', datetime('now'));
                        
                        fprintf(fileID, 'No.\tTime (s)\t');
                        for iH = 1:size(ExportData.HallSensorData, 2)
                            fprintf(fileID, 'HS#%d (V)\t', iH);
                        end
                        fprintf(fileID, 'ECD (mm)\r\n');
                        for iN = 1:length(ExportData.Time)
                            fprintf(fileID, '%07d\t', iN);
                            fprintf(fileID, '%.5f\t', ExportData.Time(iN));
                            for iH = 1:size(ExportData.HallSensorData, 2)
                                fprintf(fileID, '%.7f\t', ExportData.HallSensorData(iN, iH));
                            end
                            fprintf(fileID, '%.4f\r\n', ExportData.EncoderData(iN));
                        end
                        fprintf(fileID, '\r\n');
                        fclose(fileID);
                    otherwise
                        save([ExportPath, ExportFile], 'ExportData', '-mat');
                end
                RefreshLog(app, 1, 'Data exported to: ');
                RefreshLog(app, 1, [ExportPath, ExportFile]);
            end
        end

        % Value changed function: DisplayLengthDropDown
        function DisplayLengthDropDownValueChanged(app, event)
            UpdatePlot(app);
        end

        % Button pushed function: ResetZeroButton
        function ResetZeroButtonPushed(app, event)
            app.EncoderZeroValue = app.RecordData.EncoderData(end);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            [InportFile, InportPath] = uigetfile( ...
                {'*.mat','MAT-files (*.mat)'}, ...
                'Inport Calibration Data', [app.DefExportPath, 'ExportData.mat']);
            figure(app.LDMPUIFigure);
            % Operation cancelled
            if InportPath == 0
                RefreshLog(app, 1, 'Calibration operation cancelled.');
            else
                % Read file
                app.DefExportPath = InportPath;
                InportData = load([InportPath, InportFile], '-mat');
                CalibrateData = InportData.ExportData;
                
                % Calibration
                switch CalibrateModelPara(app, CalibrateData)
                    case 1
                        RefreshLog(app, 1, 'Model parameters calibrated from: ');
                        RefreshLog(app, 1, [InportPath, InportFile]);
                    case 0
                        RefreshLog(app, 1, 'ERROR: calibration failed: not enough data.');
                end
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            CalFFT(app, app.RecordData);
            if DisplaySpectrum(app)
                RefreshLog(app, 2, 'Spectrum obtained.');
            else
                RefreshLog(app, 2, 'WARNING: no sample data recorded.')
            end
        end

        % Button pushed function: LoadButton_Spectrum
        function LoadButton_SpectrumPushed(app, event)
            [InportFile, InportPath] = uigetfile( ...
                {'*.mat','MAT-files (*.mat)'}, ...
                'Inport Sample Data', [app.DefExportPath, 'ExportData.mat']);
            figure(app.LDMPUIFigure);
            % Operation cancelled
            if InportPath == 0
                RefreshLog(app, 2, 'Load operation cancelled.');
            else
                % Read file
                app.DefExportPath = InportPath;
                InportData = load([InportPath, InportFile], '-mat');
                
                CalFFT(app, InportData.ExportData);
                if DisplaySpectrum(app)
                    RefreshLog(app, 2, 'Spectrum obtained.');
                else
                    RefreshLog(app, 2, 'WARNING: no sample data recorded.')
                end
            end
        end

        % Button pushed function: ExportButton_Spectrum
        function ExportButton_SpectrumPushed(app, event)
            [ExportFile, ExportPath, ExportType] = uiputfile( ...
                {'*.mat','MAT-files (*.mat)'}, ...
                'Export Data Spectrum', [app.DefExportPath, 'ExportSpectrum.mat']);
            figure(app.LDMPUIFigure);
            % Operation cancelled
            if ExportPath == 0
                RefreshLog(app, 2, 'Export operation cancelled.');
            else
                % Save file
                app.DefExportPath = ExportPath;
                ExportSpectrum = app.DataSpectrum;
                switch ExportType
                    case 1
                        save([ExportPath, ExportFile], 'ExportSpectrum', '-mat');
                    case 2 % Unused
                        fileID = fopen([ExportPath, ExportFile], 'a');
                        fprintf(fileID, 'Spectrum calculated by LDMP\r\n');
                        fprintf(fileID, 'Date & Time: %s', datetime('now'));
                        
                        fclose(fileID);
                    otherwise
                        save([ExportPath, ExportFile], 'ExportSpectrum', '-mat');
                end
                RefreshLog(app, 2, ['Spectrum exported to: ', ExportPath, ExportFile]);
            end
        end

        % Value changed function: HallSensor_1
        function HallSensor_1ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_2
        function HallSensor_2ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_3
        function HallSensor_3ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_4
        function HallSensor_4ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_5
        function HallSensor_5ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_6
        function HallSensor_6ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_7
        function HallSensor_7ValueChanged(app, event)
            UpdatePlot(app);
        end

        % Value changed function: HallSensor_8
        function HallSensor_8ValueChanged(app, event)
            UpdatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LDMPUIFigure = uifigure('Visible', 'off');
            app.LDMPUIFigure.Name = 'LDMP';
            app.LDMPUIFigure.CloseRequestFcn = createCallbackFcn(app, @LDMPUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.LDMPUIFigure);
            app.SampleTab = uitab(app.TabGroup);
            app.SampleTab.Title = 'Sample';
            app.GridLayout_Sample = uigridlayout(app.SampleTab);
            app.Panel_Options = uipanel(app.GridLayout_Sample);
            app.GridLayout_Options = uigridlayout(app.Panel_Options);
            app.ExportButton_Commands = uibutton(app.GridLayout_Options, 'push');
            app.ExportButton_Commands.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_CommandsPushed, true);
            app.ExportButton_Commands.Text = 'Export';
            app.Switch = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.DisplayLengthDropDownLabel = uilabel(app.GridLayout_Options);
            app.DisplayLengthDropDownLabel.Text = 'Display Length';
            app.DisplayLengthDropDown = uidropdown(app.GridLayout_Options);
            app.DisplayLengthDropDown.Items = {'100', '500', '1000', '5000', '10000'};
            app.DisplayLengthDropDown.ItemsData = [100 500 1000 5000 10000];
            app.DisplayLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayLengthDropDownValueChanged, true);
            app.DisplayLengthDropDown.Value = 100;
            app.Lamp = uilamp(app.GridLayout_Options);
            app.PanelDisplayChannels = uipanel(app.GridLayout_Options);
            app.GridLayout_DisplayChannels = uigridlayout(app.PanelDisplayChannels);
            app.HallSensorsAnalogInputDisplayChannelsLabel = uilabel(app.GridLayout_DisplayChannels);
            app.HallSensorsAnalogInputDisplayChannelsLabel.Text = {'Hall Sensors Analog Input'; 'Display Channels'};
            app.HallSensor_1 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_1.ValueChangedFcn = createCallbackFcn(app, @HallSensor_1ValueChanged, true);
            app.HallSensor_1.Text = '#1';
            app.HallSensor_1.Value = true;
            app.HallSensor_2 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_2.ValueChangedFcn = createCallbackFcn(app, @HallSensor_2ValueChanged, true);
            app.HallSensor_2.Text = '#2';
            app.HallSensor_3 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_3.ValueChangedFcn = createCallbackFcn(app, @HallSensor_3ValueChanged, true);
            app.HallSensor_3.Text = '#3';
            app.HallSensor_4 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_4.ValueChangedFcn = createCallbackFcn(app, @HallSensor_4ValueChanged, true);
            app.HallSensor_4.Text = '#4';
            app.HallSensor_5 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_5.ValueChangedFcn = createCallbackFcn(app, @HallSensor_5ValueChanged, true);
            app.HallSensor_5.Text = '#5';
            app.HallSensor_5.Value = true;
            app.HallSensor_6 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_6.ValueChangedFcn = createCallbackFcn(app, @HallSensor_6ValueChanged, true);
            app.HallSensor_6.Text = '#6';
            app.HallSensor_7 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_7.ValueChangedFcn = createCallbackFcn(app, @HallSensor_7ValueChanged, true);
            app.HallSensor_7.Text = '#7';
            app.HallSensor_8 = uicheckbox(app.GridLayout_DisplayChannels);
            app.HallSensor_8.ValueChangedFcn = createCallbackFcn(app, @HallSensor_8ValueChanged, true);
            app.HallSensor_8.Text = '#8';
            app.SampleRatekHzSpinnerLabel = uilabel(app.GridLayout_Options);
            app.SampleRatekHzSpinnerLabel.Text = 'Sample Rate (kHz)';
            app.SampleRatekHzSpinner = uispinner(app.GridLayout_Options);
            app.SampleRatekHzSpinner.Limits = [1 20];
            app.SampleRatekHzSpinner.Value = 1;
            app.DAQCardDropDownLabel = uilabel(app.GridLayout_Options);
            app.DAQCardDropDownLabel.Text = 'DAQ Card';
            app.DAQCardDropDown = uidropdown(app.GridLayout_Options);
            app.DAQCardDropDown.Items = {'Virtual', 'NI'};
            app.DAQCardDropDown.ItemsData = [0 1];
            app.DAQCardDropDown.Tooltip = {''};
            app.DAQCardDropDown.Value = 0;
            app.PauseButton = uibutton(app.GridLayout_Options, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.Panel_Display = uipanel(app.GridLayout_Sample);
            app.GridLayout_Display = uigridlayout(app.Panel_Display);
            app.OperationLogTextAreaLabel = uilabel(app.GridLayout_Display);
            app.OperationLogTextAreaLabel.Text = 'Operation Log';
            app.OperationLogTextArea = uitextarea(app.GridLayout_Display);
            app.OperationLogTextArea.Editable = 'off';
            app.RefDispEditFieldLabel = uilabel(app.GridLayout_Display);
            app.RefDispEditFieldLabel.Text = 'Ref. Disp.';
            app.RefDispEditField = uieditfield(app.GridLayout_Display, 'numeric');
            app.RefDispEditField.ValueDisplayFormat = '%.4f';
            app.RefDispEditField.Editable = 'off';
            app.MeaDispEditFieldLabel = uilabel(app.GridLayout_Display);
            app.MeaDispEditFieldLabel.Text = 'Mea. Disp.';
            app.MeaDispEditField = uieditfield(app.GridLayout_Display, 'numeric');
            app.MeaDispEditField.ValueDisplayFormat = '%.4f';
            app.MeaDispEditField.Editable = 'off';
            app.mmLabel_1 = uilabel(app.GridLayout_Display);
            app.mmLabel_1.Text = 'mm';
            app.mmLabel_2 = uilabel(app.GridLayout_Display);
            app.mmLabel_2.Text = 'mm';
            app.CalibrateButton = uibutton(app.GridLayout_Display, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ResetZeroButton = uibutton(app.GridLayout_Display, 'push');
            app.ResetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZeroButtonPushed, true);
            app.ResetZeroButton.Text = 'Reset Zero';
            app.UIAxes_Encoder = uiaxes(app.GridLayout_Sample);
            title(app.UIAxes_Encoder, 'Encoder')
            xlabel(app.UIAxes_Encoder, 'Time (ms)')
            ylabel(app.UIAxes_Encoder, 'Displacement (mm)')
            app.UIAxes_HallSensor = uiaxes(app.GridLayout_Sample);
            title(app.UIAxes_HallSensor, 'Hall Sensor')
            xlabel(app.UIAxes_HallSensor, 'Time (ms)')
            ylabel(app.UIAxes_HallSensor, 'Magnitude')
            app.UIAxes_HallSensor_Encoder = uiaxes(app.GridLayout_Sample);
            title(app.UIAxes_HallSensor_Encoder, 'Hall Sensor - Encoder')
            xlabel(app.UIAxes_HallSensor_Encoder, 'Displacement (mm)')
            ylabel(app.UIAxes_HallSensor_Encoder, 'Magnitude')
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.GridLayout_Analyze = uigridlayout(app.AnalyzeTab);
            app.TextArea_Spectrum = uitextarea(app.GridLayout_Analyze);
            app.TextArea_Spectrum.Editable = 'off';
            app.TextArea_Spectrum.Value = {'Primary peaks: '};
            app.Panel_Spectrum = uipanel(app.GridLayout_Analyze);
            app.GridLayout_Spectrum = uigridlayout(app.Panel_Spectrum);
            app.CalculateButton = uibutton(app.GridLayout_Spectrum, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ExportButton_Spectrum = uibutton(app.GridLayout_Spectrum, 'push');
            app.ExportButton_Spectrum.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_SpectrumPushed, true);
            app.ExportButton_Spectrum.Text = 'Export';
            app.OperationLogTextArea_2Label = uilabel(app.GridLayout_Spectrum);
            app.OperationLogTextArea_2Label.Text = 'Operation Log';
            app.OperationLogTextArea_Ana = uitextarea(app.GridLayout_Spectrum);
            app.OperationLogTextArea_Ana.Editable = 'off';
            app.LoadButton_Spectrum = uibutton(app.GridLayout_Spectrum, 'push');
            app.LoadButton_Spectrum.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_SpectrumPushed, true);
            app.LoadButton_Spectrum.Text = 'Load Data';
            app.UIAxes_Spectrum = uiaxes(app.GridLayout_Analyze);
            title(app.UIAxes_Spectrum, 'Spectrum: Hall Sensors - Encoder')
            xlabel(app.UIAxes_Spectrum, 'Frequency (mm^{-1})')
            ylabel(app.UIAxes_Spectrum, 'Magnitude')
            app.LinearDisplacementMeasurementPlatformLabel = uilabel(app.LDMPUIFigure);
            app.LinearDisplacementMeasurementPlatformLabel.Text = 'Linear Displacement Measurement Platform';
            % Show the figure after all components are created
            app.LDMPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LDMP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LDMPUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.LDMPUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LDMPUIFigure)
        end
    end
end