classdef LDMP_ref < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NI_SampleUIFigure           matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        SamplingOptionsPanel        matlab.ui.container.Panel
        RateHzEditFieldLabel        matlab.ui.control.Label
        RateHzEditField             matlab.ui.control.NumericEditField
        SampleChannelLabel          matlab.ui.control.Label
        SampleChannelListBox        matlab.ui.control.ListBox
        PressCtrlShifttoselectmultiplechannelsLabel  matlab.ui.control.Label
        DisplaymodeButtonGroup      matlab.ui.container.ButtonGroup
        FixedlengthButton           matlab.ui.control.RadioButton
        OverallButton               matlab.ui.control.RadioButton
        ExportButton                matlab.ui.control.Button
        SampleLamp                  matlab.ui.control.Lamp
        SampleSwitchLabel           matlab.ui.control.Label
        SampleSwitch                matlab.ui.control.Switch
        RefDispCtrmmEditFieldLabel  matlab.ui.control.Label
        RefDispCtrmmEditField       matlab.ui.control.NumericEditField
        RefDispAImmEditFieldLabel   matlab.ui.control.Label
        RefDispAImmEditField        matlab.ui.control.NumericEditField
        CalibrateButton             matlab.ui.control.Button
        ResetCtrCheckBox            matlab.ui.control.CheckBox
        SamplingPlotPanel           matlab.ui.container.Panel
        SamplePlotUIAxes1           matlab.ui.control.UIAxes
        SamplePlotUIAxes2           matlab.ui.control.UIAxes
        SamplePlotUIAxes3           matlab.ui.control.UIAxes
        AnalysisTab                 matlab.ui.container.Tab
        FFTPanel                    matlab.ui.container.Panel
        SamplePlotUIAxesFFT         matlab.ui.control.UIAxes
        FFTButton                   matlab.ui.control.Button
        NIDATASAMPLINGLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CtrThreshold % Threshold for unsigned int to signed int (used in Ctr)
        CtrRatio % Ratio between displacement and counter (used in Ctr)
        DisplayMode % Display mode
        DisplayChannels % Channels to be displayed in the plot
        IsResetZ % Reset counterZ or not before restarting sampling (used in Ctr)
        ValueResetZ % Reset value of counterZ (used in Ctr)
        P1 % Plot handle1 AI
        P2 % Plot handle2 Ctr
        P3 % plot handle3 AI - Ctr
        P4 % plot handle4 FFT: AI - Ctr
        PlotLegend % Legend for data from Hall sensors
        PlotLength % Data to be plotted in fixed-length display mode
        RecordTime % TimeStamps
        RecordAIData % Sample Data of Hall sensors
        RecordCtrData % Sample Data of counter
        SampleSession % Data acquisition session
        SessionListener % Listener for DAQ
        TStart % Reference time 1 used for plot
        TStop % Reference time 2 used for plot
        w % Pitch of the slide way
        Para % Parameters of the NCMFD model
        InterpTable % Data points for interpolation after calibration
    end
    
    methods (Access = private)
        
        % This function is used to init the figure
        function InitPlot(app)
            tp = 0;
            yp = 0;
            app.P1 = plot(app.SamplePlotUIAxes1, tp, yp);
            axis(app.SamplePlotUIAxes1, [0 1 -5 5]);
            xlabel(app.SamplePlotUIAxes1, 'Time (s)');
            ylabel(app.SamplePlotUIAxes1, 'Voltage (V)');
            app.PlotLegend = {'#1', '#2', '#3', '#4', '#5', '#6', '#7', '#8'};
            app.DisplayChannels = [1];
            
            app.P2 = plot(app.SamplePlotUIAxes2, tp, yp);
            axis(app.SamplePlotUIAxes2, [0 1 -50 50]);
            xlabel(app.SamplePlotUIAxes2, 'Time (s)');
            ylabel(app.SamplePlotUIAxes2, 'Displacement (mm)');
            
            app.P3 = plot(app.SamplePlotUIAxes2, tp, yp);
            axis(app.SamplePlotUIAxes3, [0 300 -5 5]);
            xlabel(app.SamplePlotUIAxes3, 'Displacement (mm)');
            ylabel(app.SamplePlotUIAxes3, 'Voltage (V)');
            
            app.DisplayMode = 2;
            app.PlotLength = 1e3;
            
            app.P4 = plot(app.SamplePlotUIAxesFFT, tp, yp);
            axis(app.SamplePlotUIAxesFFT, [0 0.5 0 1]);
            xlabel(app.SamplePlotUIAxesFFT, 'Repeation (mm^-1)');
            ylabel(app.SamplePlotUIAxesFFT, 'Voltage (V)');
        end
        
        % This function is used to update the figure
        function UpdatePlot(app)
            DataLength = length(app.RecordTime);
            if DataLength < app.PlotLength
                % if there is not enough data, app will not plot
            else
                switch app.DisplayMode
                    case 1 % Fixed-length
                        StartPos = DataLength - app.PlotLength + 1;
                    case 2 % Overall
                        if DataLength > 600e3 % if the sampling time is too long, Overall mode will display a part of data
                            StartPos = DataLength - 600e3;
                        else
                            StartPos = 1;
                        end
                    otherwise % Default: Overall
                        StartPos = DataLength - app.PlotLength + 1;
                end
                tp = app.RecordTime(StartPos:DataLength); % TimeStamps
                yp1 = app.RecordAIData(StartPos:DataLength, app.DisplayChannels); % AI
                yp2 = app.RecordCtrData(StartPos:DataLength); % Ctr
                
                app.P1 = plot(app.SamplePlotUIAxes1, tp, yp1, 'LineStyle', 'none', 'Marker', '.', 'MarkerSize', 5);
                axis(app.SamplePlotUIAxes1, [tp(1) tp(end) -5 5]);
                legend(app.SamplePlotUIAxes1, app.PlotLegend(app.DisplayChannels));
                
                app.P2 = plot(app.SamplePlotUIAxes2, tp, yp2, 'LineStyle', 'none', 'Marker', '.', 'MarkerSize', 5);
                axis(app.SamplePlotUIAxes2, [tp(1) tp(end) -350 350]);
                legend(app.SamplePlotUIAxes2, 'Ctr');
                
                app.P3 = plot(app.SamplePlotUIAxes3, yp2, yp1, 'LineStyle', 'none', 'Marker', '.', 'MarkerSize', 5);
                axis(app.SamplePlotUIAxes3, [-inf inf -5 5]);
                legend(app.SamplePlotUIAxes3, app.PlotLegend(app.DisplayChannels));
            end
        end
        
        % This function is used to update the EditField
        function UpdateEditField(app)
            app.RefDispCtrmmEditField.Value = app.RecordCtrData(end);
            % Uncomment to use the method; comment to disable the method
            % Gaussian-Newton Method
            %             CalibrateData = [app.w; app.Para];
            % Interpolation method
            CalibrateData = app.InterpTable;
            app.RefDispAImmEditField.Value = NCMFDfun(app.RecordAIData(end, :), app.RecordCtrData(end), CalibrateData);
        end
        
        % This function is used to plot FFT of the data
        function FFTPlot(app)
            % Constant
            Pitch = 32;
            PitchNum = Pitch * app.CtrRatio;
            % Data extraction
            AI = app.RecordAIData;
            Ctr = app.RecordCtrData;
            if isempty(AI) || isempty(Ctr)
                % If there is no data, process the code here
            else
                % Preprocess: data with same Ctr and different AI may
                % exist. These data need preprocess such as averaging
                % 可能存在编码器数据相同模拟量数据不同的点，需要对它们预处理
                [uCtr, ~, idCtr] = unique(Ctr, 'stable');
                mAI = zeros(length(uCtr), size(AI, 2));
                for iCh = 1:size(AI, 2)
                    mAI(:, iCh) = accumarray(idCtr, AI(:, iCh), [], @mean); % average AI with same Ctr respectively
                end
                minCtr = min(uCtr); maxCtr = max(uCtr);
                stepCtr = 1/app.CtrRatio; % define interp step
                
                if minCtr == maxCtr
                    CtrExt = minCtr;
                    AIExt = mAI;
                else
                    % Interpolate data
                    CtrExt = (minCtr:stepCtr:maxCtr)';
                    AIExt = interp1(uCtr, mAI, CtrExt, 'spline');
                end
                
                % For better display effect, FFT is conducted on integral
                % period of data
                % 为了更好的FFT显示效果，FFT的数据截取为整段周期的部分
                PeriodNum = floor((length(CtrExt) / PitchNum));
                if PeriodNum < 1
                    fftLength = length(CtrExt);
                else
                    fftLength = PitchNum * PeriodNum;
                    CtrExt = CtrExt(1:fftLength);
                    AIExt = AIExt(1:fftLength, :);
                end
                
                % Plot FFT
                %                 fftLength = 2^16;
                fAI = fft(AIExt, fftLength, 1)/length(CtrExt)*2;
                fCtr = (0:(1/fftLength):(1-1/fftLength))/stepCtr;
                app.P4 = plot(app.SamplePlotUIAxesFFT, fCtr, abs(fAI));
                axis(app.SamplePlotUIAxesFFT, [0 0.5 1e-5 3]);
                set(app.SamplePlotUIAxesFFT, 'YMinorTick', 'on', 'YScale', 'log');
                legend(app.SamplePlotUIAxesFFT, app.PlotLegend);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This function is executed after startup
            app.CtrThreshold = 2^31;
            app.IsResetZ = true;
            app.ValueResetZ = 0;
            app.w = 2*pi/32;
            app.Para = zeros(88, 1);
            app.InterpTable = [];
            InitPlot(app);
        end

        % Value changed function: SampleSwitch
        function SampleSwitchValueChanged(app, event)
            % When the switch is on, start sampling
            if strcmp(app.SampleSwitch.Value, 'on')
                
                % Enable/disable components
                app.RateHzEditField.Enable = 'off';
                app.ExportButton.Enable = 'off';
                app.CalibrateButton.Enable = 'off';
                app.FFTButton.Enable = 'off';
                app.ResetCtrCheckBox.Enable = 'off';
                
                % Create session and listener
                Rate = app.RateHzEditField.Value;
                % Save the lastest value of Ctr as 'zero'
                if app.IsResetZ
                    app.ValueResetZ = 0;
                else
                    if isempty(app.RecordCtrData)
                        app.ValueResetZ = 0;
                    else
                        app.ValueResetZ = app.RecordCtrData(end);
                    end
                end
                app.RecordTime = [];
                app.RecordAIData = [];
                app.RecordCtrData = [];
                app.SampleSession = daq.createSession('ni'); % create DAQ
                
                % Hall Sensors
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai0', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai1', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai2', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai3', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai4', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai5', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai6', 'Voltage');
                addAnalogInputChannel(app.SampleSession, 'Dev1', 'ai7', 'Voltage');
                app.SampleSession.Rate = Rate; % Sample rate
                app.SampleSession.IsContinuous = true; % Data will be sampled continuously or not
                app.SampleSession.NotifyWhenDataAvailableExceeds = 1e3; % Define data length for triggering 'DataAvailable' event
                app.SessionListener = addlistener(app.SampleSession, 'DataAvailable', @SaveData);
                
                % Counter
                Ctr1 = addCounterInputChannel(app.SampleSession, 'Dev1', 'ctr0', 'Position');
                Ctr1.EncoderType = 'X4'; % Counter Mode: X1/X2/X4
                Ctr1.ZResetEnable = true; % Set the zero
                Ctr1.ZResetCondition = 'BothLow'; % Options: 'BothHigh'/'BothLow'/'AHigh'/'BHigh'
                Ctr1.ZResetValue = 0; % The 'zero'
                app.CtrRatio = 200; % 200 when EncoderType is X4
                
                startBackground(app.SampleSession); % Start DAQ session
                app.TStart = tic; % Start timing
                
                % Lamp color: green
                app.SampleLamp.Color = [0 1 0];
            else
                
                % Enable/disable components
                app.RateHzEditField.Enable = 'on';
                app.ExportButton.Enable = 'on';
                app.CalibrateButton.Enable = 'on';
                app.FFTButton.Enable = 'on';
                app.ResetCtrCheckBox.Enable = 'on';
                app.ResetCtrCheckBox.Value = false;
                app.IsResetZ = false;
                
                % Save the lastest value of Ctr as 'zero'
                if isempty(app.RecordCtrData)
                    app.ValueResetZ = 0;
                else
                    app.ValueResetZ = app.RecordCtrData(end);
                end
                
                % release the DAQ session to prevent memory leak
                stop(app.SampleSession);
                delete(app.SessionListener);
                clear app.SampleSession
                
                % Lamp color: gray
                app.SampleLamp.Color = [0.5 0.5 0.5];
            end
            
            % This function is used to save new data stored in SessionListener
            % and refresh GUI
            function SaveData(~, event)
                app.RecordTime = [app.RecordTime; event.TimeStamps]; % Store TimeStamps
                % Data of Ctr need preprocess
                CtrData = event.Data(:, end);
                IsNegative = CtrData > app.CtrThreshold;
                CtrData(IsNegative) = CtrData(IsNegative) - 2*app.CtrThreshold; % unsigned int -> signed int
                CtrData = CtrData / app.CtrRatio;
                CtrData = CtrData + app.ValueResetZ;
                app.RecordAIData = [app.RecordAIData; event.Data(:, 1:end-1)]; % Store AI
                app.RecordCtrData = [app.RecordCtrData; CtrData]; % Store Ctr
                app.TStop = toc(app.TStart); % Stop timing
                if app.TStop > 0.5 % Keep a refresh rate of 2 Hz for GUI
                    UpdateEditField(app); % Update displacement
                    UpdatePlot(app); % Updata plot
                    app.TStart = tic; % Start timing
                end
            end
        end

        % Value changed function: SampleChannelListBox
        function SampleChannelListBoxValueChanged(app, event)
            % This function is used to select and display the channels to
            % be dsiplayed in the plot
            app.DisplayChannels = [];
            IsDisplayed = ismember(app.PlotLegend, app.SampleChannelListBox.Value); % Get the chosen channel name
            for iI = 1:length(IsDisplayed)
                if IsDisplayed(iI)
                    app.DisplayChannels = [app.DisplayChannels iI];
                end
            end
            UpdatePlot(app);
        end

        % Selection changed function: DisplaymodeButtonGroup
        function DisplaymodeButtonGroupSelectionChanged(app, event)
            % This function is used to obtain the mode user select for
            % display
            SelectedButton = app.DisplaymodeButtonGroup.SelectedObject.Text;
            switch SelectedButton
                case 'Fixed-length'
                    app.DisplayMode = 1;
                case 'Overall'
                    app.DisplayMode = 2;
                otherwise
                    app.DisplayMode = 1;
            end
            UpdatePlot(app);
        end

        % Close request function: NI_SampleUIFigure
        function NI_SampleUIFigureCloseRequest(app, event)
            % This function is executed before close the app
            % It should be guaranteed that the memory will be released even
            % if the DAQ session is not terminated beforing closing app
            if exist('app.SampleSession', 'var')
                % release the DAQ session
                stop(app.SampleSession);
                delete(app.SessionListener);
                clear app.SampleSession
            end
            delete(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % This function is used to export data
            app.ExportButton.Text = 'Exporting';
            pause(0.1);
            
            ExportInfo = datestr(now, 'yyyymmdd_HHMMSS');
            ExportID = ['ExportData_' ExportInfo '.mat']; % filename
            ExportTime = app.RecordTime;
            ExportAIData = app.RecordAIData;
            ExportCtrData = app.RecordCtrData;
            % For debug: save data to base workspace
            %             assignin('base', 'ExportTime', ExportTime);
            %             assignin('base', 'ExportAIData', ExportAIData);
            %             assignin('base', 'ExportCtrData', ExportCtrData);
            % Save as .mat
            save(ExportID, 'ExportInfo', 'ExportTime', 'ExportAIData', 'ExportCtrData', '-mat');
            
            % Save as .txt
            % Saving as .txt takes a lot of time and space, for normal use
            % it is suggested to disable it
            %             fileID = fopen('ExportData.txt', 'a');
            %             fprintf(fileID, 'NI SAMPLED DATA\n');
            %             fprintf(fileID, 'Date: %s\tTime: %s\n\n', date, time);
            %
            %             fprintf(fileID, 'No.\tTime(s)\t');
            %             for iA = 1:size(ExportAIData, 2)
            %                 fprintf(fileID, 'AIData#%d(V)\t', iA);
            %             end
            %             fprintf(fileID, 'CtrData(mm)\n');
            %             DataLength = length(ExportTime);
            %             for iD = 1:DataLength
            %                 fprintf(fileID, '%07d\t', iD);
            %                 fprintf(fileID, '%.5f\t', ExportTime(iD));
            %                 for iA = 1:size(ExportAIData, 2)
            %                     fprintf(fileID, '%.7f\t', ExportAIData(iD, iA));
            %                 end
            %                 fprintf(fileID, '%.4f\n', ExportCtrData(iD));
            %             end
            %             fprintf(fileID, '\n');
            %             fclose(fileID);
            
            app.ExportButton.Text = 'Exported';
            pause(0.5);
            app.ExportButton.Text = 'Export';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % This function is used to obtain the calibrated model based on
            % AIData
            app.CalibrateButton.Text = 'Calibrating';
            pause(0.1);
            
            %             % Calibration Method: Gaussian-Newton Method
            %             % Model: YAI(n) = sum_{k}{(As(n) * sin(k * w * x) + ...
            %             % Ac(n) * cos(k * w * x)) + D(n)}
            %             % Unknown parameters: w, As, Ac, D
            %             AI = app.RecordAIData;
            %             Ctr = app.RecordCtrData;
            %             if isempty(AI) || isempty(Ctr)
            %                 % Process if there is no data
            %             else
            %                 app.w = 2*pi/32;
            %                 Order = 5;
            %                 As = zeros(Order, 8); As(1, :) = 2.5; As(3, :) = 0.25; As(5, :) = 0.033;
            %                 Ac = zeros(Order, 8);
            %                 D = zeros(1, 8);
            %                 app.Para = reshape([As; Ac; D], [], 1); % contain all parameters above
            %                 LoopTime = 5;
            %                 for iLoop = 1:LoopTime
            %                     app.Para = reshape(app.Para, [], length(D));
            %                     As = app.Para(1:Order, :);
            %                     Ac = app.Para((1:Order) + Order, :);
            %                     D = app.Para(end, :);
            %                     Yest = HallModel(Ctr, app.w, As, Ac, D, Order);
            %                     Jest = HallModel_Jacobi(Ctr, app.w, As, Ac, D, Order);
            %                     app.w = app.w + sum(Jest .* (AI - Yest), 'all')/sum(Jest.^2, 'all');
            %                     Mest = HallModel_Mat(Ctr, app.w, As, Ac, D, Order);
            %                     app.Para = (Mest'*Mest) \ Mest' * reshape(AI, [], 1);
            %                 end
            %             end
            %
            %             % This function is used to calculate Y for Y = F(X)
            %             function YAI = HallModel(x, w, As, Ac, D, ModelOrder)
            %                 YAI = zeros(length(x), length(D));
            %                 for iM = 1:ModelOrder
            %                     YAI = YAI + As(iM, :) .* sin(iM * w * x) + ...
            %                         Ac(iM, :) .* cos(iM * w * x) + D;
            %                 end
            %             end
            %
            %             % This function is used to calculate Jacobi matrix for Y = F(X)
            %             function JAI = HallModel_Jacobi(x, w, As, Ac, D, ModelOrder)
            %                 JAI = zeros(length(x), length(D));
            %                 for iM = 1:ModelOrder
            %                     JAI = JAI + (As(iM, :) .* cos(iM * w * x) + ...
            %                         Ac(iM, :) .* sin(iM * w * x)) * iM .* x;
            %                 end
            %             end
            %
            %             % This function is used to calculate coefficient of parameters
            %             function MAI = HallModel_Mat(x, w, ~, ~, D, ModelOrder)
            %                 ModelOrderExt = 2*ModelOrder+1;
            %                 MAI = zeros(length(x)*length(D), ModelOrderExt*length(D));
            %                 for iD = 0:(length(D)-1)
            %                     for iM = 1:ModelOrder
            %                         MAI(length(x) * iD + (1:length(x)), iD * ModelOrderExt + iM) ...
            %                             = sin(iM * w * x);
            %                         MAI(length(x) * iD + (1:length(x)), iD * ModelOrderExt + iM + ModelOrder) ...
            %                             = cos(iM * w * x);
            %                     end
            %                     MAI(length(x) * iD + (1:length(x)), iD * ModelOrderExt + ModelOrderExt) = 1;
            %                 end
            %             end
            
            % Calibration Method: Interpolation
            AI = app.RecordAIData;
            Ctr = app.RecordCtrData;
            if isempty(AI) || isempty(Ctr)
                % Process if there is no data
            else
                % Preprocess: data with same Ctr and different AI may
                % exist. These data need preprocess such as averaging
                % 可能存在编码器数据相同模拟量数据不同的点，需要对它们预处理
                [uCtr, ~, idCtr] = unique(Ctr, 'stable');
                mAI = zeros(length(uCtr), size(AI, 2));
                for iCh = 1:size(AI, 2)
                    mAI(:, iCh) = accumarray(idCtr, AI(:, iCh), [], @mean);
                end
                minCtr = min(uCtr); maxCtr = max(uCtr);
                stepCtr = 1/app.CtrRatio;
            end
            % interpolate table is available only at least a period of data
            % is recorded
            if (maxCtr - minCtr) < 35
                CtrExt = [];
                AIExt = [];
            else
                % Interpolate data
                CtrExt = (minCtr:stepCtr:maxCtr)';
                AIExt = interp1(uCtr, mAI, CtrExt, 'spline');
            end
            app.InterpTable = [CtrExt AIExt];
            
            app.CalibrateButton.Text = 'Calibrated';
            pause(0.5);
            app.CalibrateButton.Text = 'Calibrate';
        end

        % Value changed function: ResetCtrCheckBox
        function ResetCtrCheckBoxValueChanged(app, event)
            % This function is used to decide wether reset counterZ before
            % restarting sampling
            app.IsResetZ = app.ResetCtrCheckBox.Value;
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            % This function is used to calculate and plot the FFT of AI-Ctr
            % after sampling
            FFTPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NI_SampleUIFigure = uifigure('Visible', 'off');
            app.NI_SampleUIFigure.Name = 'NI_SAMPLE';
            app.NI_SampleUIFigure.CloseRequestFcn = createCallbackFcn(app, @NI_SampleUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.NI_SampleUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.SamplingOptionsPanel = uipanel(app.MainTab);
            app.SamplingOptionsPanel.Title = 'Sampling Options';
            app.RateHzEditFieldLabel = uilabel(app.SamplingOptionsPanel);
            app.RateHzEditFieldLabel.Text = 'Rate (Hz)';
            app.RateHzEditField = uieditfield(app.SamplingOptionsPanel, 'numeric');
            app.RateHzEditField.Limits = [1000 20000];
            app.RateHzEditField.ValueDisplayFormat = '%.1f';
            app.RateHzEditField.Value = 10000;
            app.SampleChannelLabel = uilabel(app.SamplingOptionsPanel);
            app.SampleChannelLabel.Text = {'Sample'; 'Channel'};
            app.SampleChannelListBox = uilistbox(app.SamplingOptionsPanel);
            app.SampleChannelListBox.Items = {'#1', '#2', '#3', '#4', '#5', '#6', '#7', '#8'};
            app.SampleChannelListBox.Multiselect = 'on';
            app.SampleChannelListBox.ValueChangedFcn = createCallbackFcn(app, @SampleChannelListBoxValueChanged, true);
            app.SampleChannelListBox.Value = {'#1'};
            app.PressCtrlShifttoselectmultiplechannelsLabel = uilabel(app.SamplingOptionsPanel);
            app.PressCtrlShifttoselectmultiplechannelsLabel.Text = {'Press '; 'Ctrl/Shift '; 'to select '; 'multiple '; 'channels'};
            app.DisplaymodeButtonGroup = uibuttongroup(app.SamplingOptionsPanel);
            app.DisplaymodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaymodeButtonGroupSelectionChanged, true);
            app.DisplaymodeButtonGroup.Title = 'Display mode';
            app.FixedlengthButton = uiradiobutton(app.DisplaymodeButtonGroup);
            app.FixedlengthButton.Text = 'Fixed-length';
            app.OverallButton = uiradiobutton(app.DisplaymodeButtonGroup);
            app.OverallButton.Text = 'Overall';
            app.OverallButton.Value = true;
            app.ExportButton = uibutton(app.SamplingOptionsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SampleLamp = uilamp(app.SamplingOptionsPanel);
            app.SampleSwitchLabel = uilabel(app.SamplingOptionsPanel);
            app.SampleSwitchLabel.Text = 'Sample';
            app.SampleSwitch = uiswitch(app.SamplingOptionsPanel, 'slider');
            app.SampleSwitch.Items = {'off', 'on'};
            app.SampleSwitch.ValueChangedFcn = createCallbackFcn(app, @SampleSwitchValueChanged, true);
            app.SampleSwitch.Value = 'off';
            app.RefDispCtrmmEditFieldLabel = uilabel(app.SamplingOptionsPanel);
            app.RefDispCtrmmEditFieldLabel.Text = {'Ref Disp.'; '(Ctr, mm)'};
            app.RefDispCtrmmEditField = uieditfield(app.SamplingOptionsPanel, 'numeric');
            app.RefDispCtrmmEditField.ValueDisplayFormat = '%.4f';
            app.RefDispAImmEditFieldLabel = uilabel(app.SamplingOptionsPanel);
            app.RefDispAImmEditFieldLabel.Text = {'Ref Disp.'; '(AI, mm)'};
            app.RefDispAImmEditField = uieditfield(app.SamplingOptionsPanel, 'numeric');
            app.RefDispAImmEditField.ValueDisplayFormat = '%.4f';
            app.CalibrateButton = uibutton(app.SamplingOptionsPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ResetCtrCheckBox = uicheckbox(app.SamplingOptionsPanel);
            app.ResetCtrCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetCtrCheckBoxValueChanged, true);
            app.ResetCtrCheckBox.Text = 'ResetCtr';
            app.ResetCtrCheckBox.Value = true;
            app.SamplingPlotPanel = uipanel(app.MainTab);
            app.SamplingPlotPanel.Title = 'Sampling Plot';
            app.SamplePlotUIAxes1 = uiaxes(app.SamplingPlotPanel);
            title(app.SamplePlotUIAxes1, 'Hall sensors (AI)')
            xlabel(app.SamplePlotUIAxes1, 'Time (s)')
            ylabel(app.SamplePlotUIAxes1, 'Voltage (V)')
            app.SamplePlotUIAxes1.YLim = [-5 5];
            app.SamplePlotUIAxes1.YTick = [-5 -2.5 0 2.5 5];
            app.SamplePlotUIAxes2 = uiaxes(app.SamplingPlotPanel);
            title(app.SamplePlotUIAxes2, 'Counter (Ctr)')
            xlabel(app.SamplePlotUIAxes2, 'Time (s)')
            ylabel(app.SamplePlotUIAxes2, 'Displacement (mm)')
            app.SamplePlotUIAxes3 = uiaxes(app.MainTab);
            title(app.SamplePlotUIAxes3, 'AI - Ctr')
            xlabel(app.SamplePlotUIAxes3, 'Displacement (mm)')
            ylabel(app.SamplePlotUIAxes3, 'Voltage (V)')
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.FFTPanel = uipanel(app.AnalysisTab);
            app.FFTPanel.Title = 'FFT';
            app.SamplePlotUIAxesFFT = uiaxes(app.FFTPanel);
            title(app.SamplePlotUIAxesFFT, 'FFT of AI - Ctr')
            xlabel(app.SamplePlotUIAxesFFT, 'Repeation (mm^-1)')
            ylabel(app.SamplePlotUIAxesFFT, 'Voltage (V)')
            app.FFTButton = uibutton(app.FFTPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.NIDATASAMPLINGLabel = uilabel(app.NI_SampleUIFigure);
            app.NIDATASAMPLINGLabel.Text = 'NI DATA SAMPLING';
            % Show the figure after all components are created
            app.NI_SampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LDMP_ref

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NI_SampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NI_SampleUIFigure)
        end
    end
end